classdef megoldas1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        TEditFieldLabel               matlab.ui.control.Label
        TEditField                    matlab.ui.control.NumericEditField
        ThEditFieldLabel              matlab.ui.control.Label
        ThEditField                   matlab.ui.control.NumericEditField
        AEditFieldLabel               matlab.ui.control.Label
        AEditField                    matlab.ui.control.NumericEditField
        FzistartalkFokEditFieldLabel  matlab.ui.control.Label
        FzistartalkFokEditField       matlab.ui.control.NumericEditField
        Panel2                        matlab.ui.container.Panel
        apLabel                       matlab.ui.control.Label
        TiLabel                       matlab.ui.control.Label
        ApResLabel                    matlab.ui.control.Label
        TiResLabel                    matlab.ui.control.Label
        DesignButton                  matlab.ui.control.Button
        SzimullsButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        SimResults                    matlab.ui.control.UIAxes
        SimU                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [Ti, Ap] = belsofunc(app, A, T, Th, rad)
            
            Ti = T;
            wc = rad/Th;
            Ap = Ti * wc / A;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow("Holtidos_szakasz.jpg", "Parent", app.UIAxes);
        end

        % Button pushed function: DesignButton
        function DesignButtonPushed(app, event)
            A = app.AEditField.Value;
            T = app.TEditField.Value;
            Th = app.ThEditField.Value;
            fazistartalek = deg2rad(app.FzistartalkFokEditField.Value);
            
            [Ti, Ap] = belsofunc(app, A, T, Th, fazistartalek);
            
            app.TiResLabel.Text = num2str(Ti);
            app.ApResLabel.Text = num2str(Ap);
        end

        % Button pushed function: SzimullsButton
        function SzimullsButtonPushed(app, event)
            load_system("Closed_loop_PI.slx");
            hws = get_param(bdroot, "ModelWorkspace");
            hws.assignin("A", app.AEditField.Value);
            hws.assignin("T", app.TEditField.Value);
            hws.assignin("Th", app.ThEditField.Value);
            hws.assignin("Ap", str2num(app.ApResLabel.Text));
            hws.assignin("Ti", str2num(app.TiResLabel.Text));
            save_system;
            sim("Closed_loop_PI.slx", "StopTime", num2str(app.ThEditField.Value * 5));
            
            load output;
            plot(app.SimResults, y(1, :), y(2,:));
            
            load outputu;
            plot(app.SimU, u(1, :), u(2,:));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TEditFieldLabel = uilabel(app.Panel);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.Panel, 'numeric');
            app.ThEditFieldLabel = uilabel(app.Panel);
            app.ThEditFieldLabel.Text = 'Th';
            app.ThEditField = uieditfield(app.Panel, 'numeric');
            app.AEditFieldLabel = uilabel(app.Panel);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.Panel, 'numeric');
            app.FzistartalkFokEditFieldLabel = uilabel(app.Panel);
            app.FzistartalkFokEditFieldLabel.Text = 'Fázistartalék (Fok)';
            app.FzistartalkFokEditField = uieditfield(app.Panel, 'numeric');
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.apLabel = uilabel(app.Panel2);
            app.apLabel.Text = 'ap';
            app.TiLabel = uilabel(app.Panel2);
            app.TiLabel.Text = 'Ti';
            app.ApResLabel = uilabel(app.Panel2);
            app.ApResLabel.Text = 'Ismeretlen';
            app.TiResLabel = uilabel(app.Panel2);
            app.TiResLabel.Text = 'Ismeretlen';
            app.DesignButton = uibutton(app.Panel2, 'push');
            app.DesignButton.ButtonPushedFcn = createCallbackFcn(app, @DesignButtonPushed, true);
            app.DesignButton.Text = 'Méretezés';
            app.SzimullsButton = uibutton(app.UIFigure, 'push');
            app.SzimullsButton.ButtonPushedFcn = createCallbackFcn(app, @SzimullsButtonPushed, true);
            app.SzimullsButton.Text = 'Szimulálás';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {''; ''})
            ylabel(app.UIAxes, {''; ''})
            zlabel(app.UIAxes, 'Z')
            app.SimResults = uiaxes(app.UIFigure);
            title(app.SimResults, 'Szakasz Kimenet')
            xlabel(app.SimResults, 'Idő (Sec)')
            ylabel(app.SimResults, {'Aplitúdó'; ''})
            zlabel(app.SimResults, 'Z')
            app.SimU = uiaxes(app.UIFigure);
            title(app.SimU, {'Beavatkozó jel'; ''})
            xlabel(app.SimU, 'Idő (Sec)')
            ylabel(app.SimU, 'Amplitúdó')
            zlabel(app.SimU, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = megoldas1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end